classdef GazeProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label_8                    matlab.ui.control.Label
        OutputButton               matlab.ui.control.Button
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        FillgapsButton             matlab.ui.control.Button
        EyegazefileEditFieldLabel  matlab.ui.control.Label
        EyegazefileEditField       matlab.ui.control.EditField
        ImportButton               matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        GenerategraphButton        matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        DetectandeliminateoutliersButton  matlab.ui.control.Button
        Label_2                    matlab.ui.control.Label
        MarkgapsButton             matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filename % Description
        timeSeries
        X
        Y
        gaps
        path
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename,path]=uigetfile('*.txt');
            app.path = path;
            app.EyegazefileEditField.Value=filename;
            %app.filename=strcat('Eye_gaze\',filename);
            app.filename=filename;
            app.Label.Text='Loading...';
            drawnow
            %[app.timeSeries,app.X,app.Y]=Input(app.filename);
            ff=readtable(filename);
            ff.Properties.VariableNames([1 2 3]) = {'time' 'X' 'Y'};
            app.X=ff.X;app.Y=ff.Y;
            app.X(app.X==0)=NaN;
            app.Y(app.Y==0)=NaN;
            app.timeSeries=1:size(app.X);
            %[app.timeSeries,app.X,app.Y]=cleaned_input(app.filename);
            app.Label.Text='Load successfully';
            T = array2table([app.timeSeries',app.X,app.Y]);
            drawnow
            app.UITable.Data=T;
        end

        % Value changed function: EyegazefileEditField
        function EyegazefileEditFieldValueChanged(app, event)
            value = app.EyegazefileEditField.Value;
            
        end

        % Button pushed function: GenerategraphButton
        function GenerategraphButtonPushed(app, event)
            plot3(app.UIAxes,app.timeSeries,app.X,app.Y);
            rotate3d(app.UIAxes,'on');
        end

        % Button pushed function: DetectandeliminateoutliersButton
        function DetectandeliminateoutliersButtonPushed(app, event)
            app.Label_2.Text='Processing...';
            drawnow
            finalZ=zeros(size(app.X,1),1);
            zscor_nan = @(x) bsxfun(@rdivide, bsxfun(@minus, x, mean(x,'omitnan')), std(x, 'omitnan'));
            % Calculating zscore using forward and backward 1000 data
            for i=1:size(app.X,1)
                start=i-1000;
                over=i+1000;
                if start<1
                    start=1;
                end
                if over>size(app.X,1)
                    over=size(app.X,1);
                end
                zzz=zscor_nan(app.X(start:over)).^2+zscor_nan(app.Y(start:over)).^2;
                finalZ(i)=zzz(i-start+1);
            end
            % Detect outliers and delete them
            % We choose Zscore threshold as 10
            outliers=finalZ>10;
            for i=1:size(app.X,1)
                if outliers(i)==1
                    app.X(i)=nan;
                    app.Y(i)=nan;
                end
            end

            for i=2:size(app.X,1)-1
                if isnan(app.X(i+1)) && isnan(app.X(i-1))
                    app.X(i)=nan;
                    app.Y(i)=nan;
                end     
            end
            
            
            for i=2:size(app.X,1)-2
                if isnan(app.X(i-1)) && isnan(app.X(i+2))
                    app.X(i)=nan;app.X(i+1)=nan;
                    app.Y(i)=nan;app.Y(i+1)=nan;
                end     
            end
            
            T = array2table([app.timeSeries',app.X,app.Y]);
            app.UITable.Data=T;
            plot3(app.UIAxes,app.timeSeries,app.X,app.Y);
            rotate3d(app.UIAxes,'on');
            app.Label_2.Text='Process completed';
        end

        % Button pushed function: MarkgapsButton
        function MarkgapsButtonPushed(app, event)
            app.gaps  = cell2table(cell(0,3), 'VariableNames', {'Gap', 'Start', 'End'});
            start=1;over=1;i=1;gap_number=1;
            while i<size(app.X,1)
                if isnan(app.X(i))
                    start=i;
                    while isnan(app.X(i))&&i<size(app.X,1)
                        i=i+1;
                    end
                    over=i-1;
                    temp= table(gap_number,start,over, 'VariableNames', {'Gap', 'Start', 'End'});
                    gap_number=gap_number+1;
                    app.gaps=[app.gaps;temp];
                else
                    i=i+1;
                end
            end
            app.UITable.ColumnName=app.gaps.Properties.VariableNames;
            app.UITable.Data=app.gaps;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            neighbors=5;
            plot3(app.UIAxes2,app.timeSeries(app.gaps.Start(indices(1))-neighbors:app.gaps.End(indices(1))+neighbors), ...
                    app.X(app.gaps.Start(indices(1))-neighbors:app.gaps.End(indices(1))+neighbors), ...
                    app.Y(app.gaps.Start(indices(1))-neighbors:app.gaps.End(indices(1))+neighbors));
        end

        % Button pushed function: FillgapsButton
        function FillgapsButtonPushed(app, event)
%             for i=1:size(app.gaps,1)
%                 if app.gaps.End(i)+3<=size(app.X,1)
%                     value1=[app.X(app.gaps.Start(i)-3),app.X(app.gaps.Start(i)-2),app.X(app.gaps.Start(i)-1)]; 
%                     value2=[app.X(app.gaps.End(i)+1),app.X(app.gaps.End(i)+2),app.X(app.gaps.End(i)+3)];
%                     time1=[app.gaps.Start(i)-3,app.gaps.Start(i)-2,app.gaps.Start(i)-1];
%                     time2=[app.gaps.End(i)+1,app.gaps.End(i)+2,app.gaps.End(i)+3];
%                     value=Cubic_spline(time1,time2,value1,value2);
%                     app.X(app.gaps.Start(i):app.gaps.End(i))=value;
%    
%                     value1=[app.Y(app.gaps.Start(i)-3),app.Y(app.gaps.Start(i)-2),app.Y(app.gaps.Start(i)-1)]; 
%                     value2=[app.Y(app.gaps.End(i)+1),app.Y(app.gaps.End(i)+2),app.Y(app.gaps.End(i)+3)];
%                     time1=[app.gaps.Start(i)-3,app.gaps.Start(i)-2,app.gaps.Start(i)-1];
%                     time2=[app.gaps.End(i)+1,app.gaps.End(i)+2,app.gaps.End(i)+3];
%                     value=Cubic_spline(time1,time2,value1,value2);
%                     app.Y(app.gaps.Start(i):app.gaps.End(i))=value;
%                 end
%             end
            app.X=fillmissing(app.X,'pchip');
            app.Y=fillmissing(app.Y,'pchip');
            plot3(app.UIAxes,app.timeSeries,app.X,app.Y);
            rotate3d(app.UIAxes,'on');     
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            output=[app.X app.Y];
            [p,f,e]=fileparts(app.filename);
            writematrix(output,strcat(app.path,strcat(f,'_gapfilled.txt')))

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'All Data')
            xlabel(app.UIAxes, 'Frame')
            ylabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Selected gap')
            xlabel(app.UIAxes2, 'Frame')
            ylabel(app.UIAxes2, 'X')
            zlabel(app.UIAxes2, 'Y')
            app.MarkgapsButton = uibutton(app.UIFigure, 'push');
            app.MarkgapsButton.ButtonPushedFcn = createCallbackFcn(app, @MarkgapsButtonPushed, true);
            app.MarkgapsButton.Text = 'Mark gaps';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.DetectandeliminateoutliersButton = uibutton(app.UIFigure, 'push');
            app.DetectandeliminateoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @DetectandeliminateoutliersButtonPushed, true);
            app.DetectandeliminateoutliersButton.Text = 'Detect and eliminate outliers';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.GenerategraphButton = uibutton(app.UIFigure, 'push');
            app.GenerategraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerategraphButtonPushed, true);
            app.GenerategraphButton.Text = 'Generate graph';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time'; 'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.EyegazefileEditField = uieditfield(app.UIFigure, 'text');
            app.EyegazefileEditField.ValueChangedFcn = createCallbackFcn(app, @EyegazefileEditFieldValueChanged, true);
            app.EyegazefileEditFieldLabel = uilabel(app.UIFigure);
            app.EyegazefileEditFieldLabel.Text = 'Eye gaze file';
            app.FillgapsButton = uibutton(app.UIFigure, 'push');
            app.FillgapsButton.ButtonPushedFcn = createCallbackFcn(app, @FillgapsButtonPushed, true);
            app.FillgapsButton.Text = 'Fill gaps';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '1.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '2.';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '3.';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '4.';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '5.';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '5.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GazeProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end